classdef TicTacToe1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TicTacToeFigure            matlab.ui.Figure
        MusicSwitchLabel           matlab.ui.control.Label
        MusicSwitch                matlab.ui.control.Switch
        TicTacToeLabel             matlab.ui.control.Label
        Box9                       matlab.ui.control.Button
        Box6                       matlab.ui.control.Button
        Box3                       matlab.ui.control.Button
        Box8                       matlab.ui.control.Button
        Box5                       matlab.ui.control.Button
        Box2                       matlab.ui.control.Button
        Box7                       matlab.ui.control.Button
        Box4                       matlab.ui.control.Button
        Box1                       matlab.ui.control.Button
        CreditsButton              matlab.ui.control.Button
        HelpButton                 matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
        WhoGoesFirstButtonGroup    matlab.ui.container.ButtonGroup
        Player2Button              matlab.ui.control.RadioButton
        Player1Button              matlab.ui.control.RadioButton
        EditPlayerName2Label       matlab.ui.control.Label
        EditPlayerName2Field       matlab.ui.control.EditField
        EditPlayerName1Label       matlab.ui.control.Label
        EditPlayerName1Field       matlab.ui.control.EditField
        ScoreBoardTable            matlab.ui.control.Table
        ScoreBoardLabel            matlab.ui.control.Label
        SelectGamemodeButtonGroup  matlab.ui.container.ButtonGroup
        PlayerVsPlayerButton       matlab.ui.control.RadioButton
        ComputerEasyButton         matlab.ui.control.RadioButton
        ComputerMediumButton       matlab.ui.control.RadioButton
        ComputerHardButton         matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        Gamestate = [ 0 0 0 ; 0 0 0 ; 0 0 0 ];
        PlayerNumber % The player number. Player 1 is 1, Player 2 is -1
        Gamemode % The selected Gamemode: 1 - PvP, 2 - Easy, 3 - Medium, 4 - Hard
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function TicTacToeStartup(app)
            assignin('base','app',app)
            rng('shuffle')
            
        end

        % Value changed function: EditPlayerName1Field
        function EditPlayerName1FieldValueChanged(app, event)
            app.Player1Button.Text = app.EditPlayerName1Field.Value;
            app.EditPlayerName1Field.Value = '';            
        end

        % Value changed function: EditPlayerName2Field
        function EditPlayerName2FieldValueChanged(app, event)
            app.Player2Button.Text = app.EditPlayerName2Field.Value;
            app.EditPlayerName2Field.Value = '';  
        end

        % Selection changed function: SelectGamemodeButtonGroup
        function SelectGamemodeButtonGroupSelectionChanged(app, event)
            app.Gamemode = (str2double(event.Source.Tag));
            
            % Changes the player 2 name to computer if the mode is not PvP.
            if app.Gamemode == 1
                app.Player1Button.Text = 'Player 1';
                app.Player2Button.Text = 'Player 2';
%             else
%                 app.Player1Button.Text = 'Player 1';
%                 app.Player2Button.Text = 'Computer';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TicTacToeFigure = uifigure('Visible', 'off');
            app.TicTacToeFigure.Name = 'MATLAB App';
            app.MusicSwitchLabel = uilabel(app.TicTacToeFigure);
            app.MusicSwitchLabel.Text = 'Music';
            app.MusicSwitch = uiswitch(app.TicTacToeFigure, 'slider');
            app.MusicSwitch.Value = 'On';
            app.TicTacToeLabel = uilabel(app.TicTacToeFigure);
            app.TicTacToeLabel.Text = 'Tic Tac Toe';
            app.Box9 = uibutton(app.TicTacToeFigure, 'push');
            app.Box9.Text = '';
            app.Box6 = uibutton(app.TicTacToeFigure, 'push');
            app.Box6.Text = '';
            app.Box3 = uibutton(app.TicTacToeFigure, 'push');
            app.Box3.Text = '';
            app.Box8 = uibutton(app.TicTacToeFigure, 'push');
            app.Box8.Text = '';
            app.Box5 = uibutton(app.TicTacToeFigure, 'push');
            app.Box5.Text = '';
            app.Box2 = uibutton(app.TicTacToeFigure, 'push');
            app.Box2.Text = '';
            app.Box7 = uibutton(app.TicTacToeFigure, 'push');
            app.Box7.Text = '';
            app.Box4 = uibutton(app.TicTacToeFigure, 'push');
            app.Box4.Text = '';
            app.Box1 = uibutton(app.TicTacToeFigure, 'push');
            app.Box1.Text = '';
            app.CreditsButton = uibutton(app.TicTacToeFigure, 'push');
            app.CreditsButton.Text = 'Credits';
            app.HelpButton = uibutton(app.TicTacToeFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.ExitButton = uibutton(app.TicTacToeFigure, 'push');
            app.ExitButton.Text = 'Exit';
            app.WhoGoesFirstButtonGroup = uibuttongroup(app.TicTacToeFigure);
            app.WhoGoesFirstButtonGroup.Title = 'Who Goes First?';
            app.Player2Button = uiradiobutton(app.WhoGoesFirstButtonGroup);
            app.Player2Button.Tag = '-1';
            app.Player2Button.Text = 'Player 2';
            app.Player1Button = uiradiobutton(app.WhoGoesFirstButtonGroup);
            app.Player1Button.Tag = '1';
            app.Player1Button.Text = 'Player 1';
            app.Player1Button.Value = true;
            app.EditPlayerName2Label = uilabel(app.TicTacToeFigure);
            app.EditPlayerName2Label.Text = 'Edit Player Name 2:';
            app.EditPlayerName2Field = uieditfield(app.TicTacToeFigure, 'text');
            app.EditPlayerName2Field.ValueChangedFcn = createCallbackFcn(app, @EditPlayerName2FieldValueChanged, true);
            app.EditPlayerName1Label = uilabel(app.TicTacToeFigure);
            app.EditPlayerName1Label.Text = 'Edit Player Name 1:';
            app.EditPlayerName1Field = uieditfield(app.TicTacToeFigure, 'text');
            app.EditPlayerName1Field.ValueChangedFcn = createCallbackFcn(app, @EditPlayerName1FieldValueChanged, true);
            app.ScoreBoardTable = uitable(app.TicTacToeFigure);
            app.ScoreBoardTable.ColumnName = {'Player 1'; 'Player 2'};
            app.ScoreBoardTable.RowName = {};
            app.ScoreBoardTable.ColumnEditable = true;
            app.ScoreBoardLabel = uilabel(app.TicTacToeFigure);
            app.ScoreBoardLabel.Text = 'Score Board';
            app.SelectGamemodeButtonGroup = uibuttongroup(app.TicTacToeFigure);
            app.SelectGamemodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectGamemodeButtonGroupSelectionChanged, true);
            app.SelectGamemodeButtonGroup.Title = 'Select Gamemode';
            app.PlayerVsPlayerButton = uiradiobutton(app.SelectGamemodeButtonGroup);
            app.PlayerVsPlayerButton.Tag = '1';
            app.PlayerVsPlayerButton.Text = 'Player Vs Player';
            app.PlayerVsPlayerButton.Value = true;
            app.ComputerEasyButton = uiradiobutton(app.SelectGamemodeButtonGroup);
            app.ComputerEasyButton.Tag = '2';
            app.ComputerEasyButton.Text = 'Computer - Easy';
            app.ComputerMediumButton = uiradiobutton(app.SelectGamemodeButtonGroup);
            app.ComputerMediumButton.Tag = '3';
            app.ComputerMediumButton.Text = 'Computer - Medium';
            app.ComputerHardButton = uiradiobutton(app.SelectGamemodeButtonGroup);
            app.ComputerHardButton.Tag = '4';
            app.ComputerHardButton.Text = 'Computer - Hard';
            % Show the figure after all components are created
            app.TicTacToeFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToe1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TicTacToeFigure)

            % Execute the startup function
            runStartupFcn(app, @TicTacToeStartup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TicTacToeFigure)
        end
    end
end